classdef Solsys_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        uitoolbar1   matlab.ui.container.Toolbar
        uipushtool2  matlab.ui.container.toolbar.PushTool
        uipushtool3  matlab.ui.container.toolbar.PushTool
        textmst      matlab.ui.control.EditField
        mstslider    matlab.ui.control.Slider
        Plotting     matlab.ui.control.DropDown
        uipanel6     matlab.ui.container.Panel
        Start        matlab.ui.control.Button
        text36       matlab.ui.control.Label
        Real_env     matlab.ui.control.CheckBox
        DHW          matlab.ui.control.CheckBox
        tstart       matlab.ui.control.EditField
        text33       matlab.ui.control.Label
        text32       matlab.ui.control.Label
        City         matlab.ui.control.DropDown
        Month_input  matlab.ui.control.EditField
        uipanel4     matlab.ui.container.Panel
        Aresidence   matlab.ui.control.EditField
        Uresidence   matlab.ui.control.EditField
        text46       matlab.ui.control.Label
        text44       matlab.ui.control.Label
        Input_ql     matlab.ui.control.EditField
        text19       matlab.ui.control.Label
        text20       matlab.ui.control.Label
        Input_ml     matlab.ui.control.EditField
        text21       matlab.ui.control.Label
        Input_tlmin  matlab.ui.control.EditField
        text22       matlab.ui.control.Label
        Input_ast    matlab.ui.control.EditField
        text23       matlab.ui.control.Label
        Input_ust    matlab.ui.control.EditField
        fText        matlab.ui.control.Label
        text5        matlab.ui.control.Label
        text4        matlab.ui.control.Label
        text2        matlab.ui.control.Label
        text1        matlab.ui.control.Label
        depthSlider  matlab.ui.control.Slider
        depthText    matlab.ui.control.EditField
        uipanel2     matlab.ui.container.Panel
        text55       matlab.ui.control.Label
        angle_g      matlab.ui.control.EditField
        text54       matlab.ui.control.Label
        angle_b      matlab.ui.control.EditField
        text9        matlab.ui.control.Label
        Input_b      matlab.ui.control.EditField
        Input_fr     matlab.ui.control.EditField
        text12       matlab.ui.control.Label
        Input_taeff  matlab.ui.control.EditField
        text11       matlab.ui.control.Label
        Tst_plot     matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function Input_maxi_Callback(app, hObject, eventdata, handles)
            % hObject    handle to Input_maxi (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Input_maxi as text
            %        str2double(get(hObject,'String')) returns contents of Input_maxi as a double
            maxistr=get(handles.Input_maxi,'String');
            maxi=str2double(maxistr);
            set(handles.Input_maxi,'Value',maxi);
        end
        
        function Input_maxi_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to Input_maxi (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function Input_mst_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to Input_mst (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function Input_mst_slid_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to Input_mst_slid (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function Input_q_Callback(app, hObject, eventdata, handles)
            % hObject    handle to Input_q (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Input_q as text
            %        str2double(get(hObject,'String')) returns contents of Input_q as a double
            qstr=get(handles.Input_q,'String');
            q=str2double(qstr);
            set(handles.Input_q,'Value',q);
        end
        
        function Input_q_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to Input_q (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function Input_td_Callback(app, hObject, eventdata, handles)
            % hObject    handle to Input_td (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Input_td as text
            %        str2double(get(hObject,'String')) returns contents of Input_td as a double
            tdstr=get(handles.Input_td,'String');
            td=str2double(tdstr);
            set(handles.Input_td,'Value',td);
        end
        
        function Input_td_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to Input_td (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function Input_trise_Callback(app, hObject, eventdata, handles)
            % hObject    handle to Input_trise (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Input_trise as text
            %        str2double(get(hObject,'String')) returns contents of Input_trise as a double
            trisestr=get(handles.Input_trise,'String');
            trise=str2double(trisestr);
            set(handles.Input_trise,'Value',trise);
        end
        
        function Input_trise_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to Input_trise (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function Year_input_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to Year_input (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function collector_Callback(app, hObject, eventdata, handles)
            % --- Executes on selection change in collector.
            
            % hObject    handle to collector (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns collector contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from collector
            c_v = get(handles.collector,'Value');
        end
        
        function collector_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to collector (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Load data for component configuration
            componentData = load('Solsys_App.mat');
            
            % Set component properties that require runtime configuration
            app.Time_dependent_load.BackgroundColor = [1 1 1;0.9412 0.9412 0.9412];
            app.Time_dependent_load.ColumnFormat = {'numeric' []};
            app.Time_dependent_load.Data = componentData.Time_dependent_load.Data;
            app.Time_dependent_load.UserData = componentData.Time_dependent_load.UserData;
        end
        
        function results = func15(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Solsys_OpeningFcn(app, varargin)
            % --- Executes just before Solsys is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Solsys (see VARARGIN)
            
            % Choose default command line output for Solsys
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: Aresidence
        function Aresidence_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Aresidence (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Aresidence as text
            %        str2double(get(hObject,'String')) returns contents of Aresidence as a double
            Aresidencest=get(handles.Aresidence,'String');
            Aresidencetr=str2double(Aresidencest);
            set(handles.Aresidence,'Value',Aresidencetr)
        end

        % Value changed function: City
        function City_Callback(app, event)
            % --- Executes on selection change in City.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to City (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns City contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from City
            city = get(handles.City,'Value');
        end

        % Value changed function: tstart
        function tstart_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Day_input (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            day=get(handles.tstart,'String');
            daystr=str2double(day);
            set(handles.tstart,'Value',daystr);
        end

        % Value changed function: Input_ast
        function Input_ast_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Input_ast (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Input_ast as text
            %        str2double(get(hObject,'String')) returns contents of Input_ast as a double
            aststr=get(handles.Input_ast,'String');
            ast=str2double(aststr);
            set(handles.Input_ast,'Value',ast);
        end

        % Value changed function: Input_b
        function Input_b_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Input_b (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Input_b as text
            %        str2double(get(hObject,'String')) returns contents of Input_b as a double
            bstr=get(handles.Input_b,'String');
            b=str2double(bstr);
            set(handles.Input_b,'Value',b);
        end

        % Callback function: not associated with a component
        function Input_dta_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Input_dta (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Input_dta as text
            %        str2double(get(hObject,'String')) returns contents of Input_dta as a double
            dtastr=get(handles.Input_dta,'String');
            dta=str2double(dtastr);
            set(handles.Input_dta,'Value',dta);
        end

        % Value changed function: Input_fr
        function Input_fr_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Input_fr (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Input_fr as text
            %        str2double(get(hObject,'String')) returns contents of Input_fr as a double
            frstr=get(handles.Input_fr,'String');
            fr=str2double(frstr);
            set(handles.Input_fr,'Value',fr);
        end

        % Value changed function: Input_ml
        function Input_ml_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Input_ml (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Input_ml as text
            %        str2double(get(hObject,'String')) returns contents of Input_ml as a double
            mlstr=get(handles.Input_ml,'String');
            ml=str2double(mlstr);
            set(handles.Input_ml,'Value',ml);
        end

        % Value changed function: Input_ql
        function Input_ql_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Input_ql (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Input_ql as text
            %        str2double(get(hObject,'String')) returns contents of Input_ql as a double
            qlstr=get(handles.Input_ql,'String');
            ql=str2double(qlstr);
            set(handles.Input_ql,'Value',ql);
        end

        % Value changed function: Input_taeff
        function Input_taeff_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Input_taeff (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Input_taeff as text
            %        str2double(get(hObject,'String')) returns contents of Input_taeff as a double
            taeffstr=get(handles.Input_taeff,'String');
            taeff=str2double(taeffstr);
            set(handles.Input_taeff,'Value',taeff);
        end

        % Callback function: not associated with a component
        function Input_tam_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Input_tam (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Input_tam as text
            %        str2double(get(hObject,'String')) returns contents of Input_tam as a double
            tamstr=get(handles.Input_tam,'String');
            tam=str2double(tamstr);
            set(handles.Input_tam,'Value',tam);
        end

        % Value changed function: Input_tlmin
        function Input_tlmin_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Input_tlmin (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Input_tlmin as text
            %        str2double(get(hObject,'String')) returns contents of Input_tlmin as a double
            tlminstr=get(handles.Input_tlmin,'String');
            tlmin=str2double(tlminstr);
            set(handles.Input_tlmin,'Value',tlmin);
        end

        % Value changed function: Input_ust
        function Input_ust_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Input_ust (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Input_ust as text
            %        str2double(get(hObject,'String')) returns contents of Input_ust as a double
            uststr=get(handles.Input_ust,'String');
            ust=str2double(uststr);
            set(handles.Input_ust,'Value',ust);
        end

        % Value changed function: Month_input
        function Month_input_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Month_input (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Month_input as text
            %        str2double(get(hObject,'String')) returns contents of Month_input as a double
            month=get(handles.Month_input,'String');
            monthstr=str2double(month);
            set(handles.Month_input,'Value',monthstr)
        end

        % Value changed function: Plotting
        function Plotting_Callback(app, event)
            % --- Executes on selection change in Plotting.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Plotting (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns Plotting contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from Plotting
            each_plot = get(handles.Plotting,'Value');
            %data=guidata(gcf)
            hax=handles.Tst_plot;
            global x;
            global TST;
            global N_A;
            global QU;
            global QS;
            global TST_str;
            global itypest;
            
            if (each_plot==1)
                if (itypest==1)
                    hax=handles.Tst_plot;
                    plot(hax,x,TST);
                    %axis(hax,[xmin, xmax, 250, 400]);
                    xlabel(hax,'t (hours X 10)');
                    ylabel(hax,'Tst(K)');
                    grid(hax,'on');
                elseif (itypest==0)
                    hax=handles.Tst_plot;
                    plot(hax,x,TST_str);
                    %axis(hax,[xmin, xmax, 250, 400]);
                    xlabel(hax,'t (hours X 10)');
                    ylabel(hax,'Tst(K)');
                    grid(hax,'on');
                end
            elseif (each_plot==2)
                hax=handles.Tst_plot;
                plot(hax,x,QU);
                %axis(hax2,[xmin, xmax, 0, 100]);
                xlabel(hax,'t (hours X 10)');
                ylabel(hax,'Qu(kW)');
                grid(hax,'on');
            elseif (each_plot==3)
                %hax3=handles.n_plot;
                plot(hax,x,N_A);
                %axis(hax3,[xmin, xmax, 0, 100]);
                xlabel(hax,'t (hours X 10)');
                ylabel(hax,'n(%)');
                grid(hax,'on');
            elseif (each_plot==4)
                %hax3=handles.n_plot;
                plot(hax,x,QS);
                %axis(hax3,[xmin, xmax, 0, 100]);
                xlabel(hax,'t (hours X 10)');
                ylabel(hax,'Qs(kW)');
                grid(hax,'on');
            end
        end

        % Callback function: not associated with a component
        function Range_input_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Range_input (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Range_input as text
            %        str2double(get(hObject,'String')) returns contents of Range_input as a double
            range=get(handles.Range_input,'String');
            rangestr=str2double(range);
            set(handles.Range_input,'Value',rangestr)
        end

        % Callback function: not associated with a component
        function Reflectivity_Callback(app, event)
            % --- Executes on selection change in Reflectivity.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Reflectivity (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns Reflectivity contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from Reflectivity
            refl= get(handles.Reflectivity,'Value');
        end

        % Callback function: not associated with a component
        function Soiltype_Callback(app, event)
            % --- Executes on selection change in Soiltype.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Soiltype (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns Soiltype contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from Soiltype
            soiltype= get(handles.Soiltype,'Value');
        end

        % Button pushed function: Start
        function Start_Callback(app, event)
            % --- Executes on button press in Start.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %uiresume(handles.figure1);
            SolsysF(handles);
        end

        % Callback function: not associated with a component
        function Stratified_Callback(app, event)
            % --- Executes on button press in Stratified.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Stratified (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Stratified_check=get(handles.Stratified, 'Value');
        end

        % Callback function: not associated with a component
        function Time_dependent_load_CellEditCallback(app, event)
            % --- Executes when entered data in editable cell(s) in Time_dependent_load.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Time_dependent_load (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.CONTROL.TABLE)
            %	Indices: row and column indices of the cell(s) edited
            %	PreviousData: previous data for the cell(s) edited
            %	EditData: string(s) entered by the user
            %	NewData: EditData or its converted form set on the Data property. Empty if Data was not changed
            %	Error: error string when failed to convert EditData to appropriate value for Data
            % handles    structure with handles and user data (see GUIDATA)
            iRowChanged=eventdata.Indices(1);
            iColChanged=eventdata.Indices(2);
            oldValue=eventdata.PreviousData;
            newValue=eventdata.NewData;
            disp(['Data at (', num2str(iRowChanged), ',', ...
            num2str(iColChanged), ')' , ' changed from ',...
            num2str(oldValue), ' to ', num2str(newValue)]);
        end

        % Value changed function: Uresidence
        function Uresidence_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Uresidence (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Uresidence as text
            %        str2double(get(hObject,'String')) returns contents of Uresidence as a double
            Uresidencest=get(handles.Uresidence,'String');
            Uresidencetr=str2double(Uresidencest);
            set(handles.Uresidence,'Value',Uresidencetr)
        end

        % Value changed function: angle_b
        function angle_b_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to angle_b (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of angle_b as text
            %        str2double(get(hObject,'String')) returns contents of angle_b as a double
            angle_bst=get(handles.angle_b,'String');
            angle_btr=str2double(angle_bst);
            set(handles.angle_b,'Value',angle_btr)
        end

        % Value changed function: angle_g
        function angle_g_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to angle_g (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of angle_g as text
            %        str2double(get(hObject,'String')) returns contents of angle_g as a double
            angle_gst=get(handles.angle_g,'String');
            angle_gtr=str2double(angle_gst);
            set(handles.angle_g,'Value',angle_gtr)
        end

        % Value changed function: depthSlider
        function depthSlider_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to depthSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            a=get(handles.depthSlider,'Value');
            astr=num2str(a);
            set(handles.depthText,'String',astr)
        end

        % Value changed function: depthText
        function depthText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to depthText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of depthText as text
            %        str2double(get(hObject,'String')) returns contents of depthText as a double
            astr=get(handles.depthText,'String');
            a=str2double(astr);
            set(handles.depthSlider,'Value',a);
        end

        % Value changed function: mstslider
        function mstslider_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mstslider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            mst=get(handles.mstslider,'Value');
            mststr=num2str(mst);
            set(handles.textmst,'String',mststr)
        end

        % Value changed function: textmst
        function textmst_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to textmst (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of textmst as text
            %        str2double(get(hObject,'String')) returns contents of textmst as a double
            mststr=get(handles.textmst,'String');
            mst=str2double(mststr);
            set(handles.mstslider,'Value',mst)
        end

        % Callback function: uipushtool3
        function uipushtool3_ClickedCallback(app, event)
            % Create a new UI figure
            uifigure('WindowStyle', app.figure1.WindowStyle);
        end

        % Callback function: uipushtool2
        function uipushtool2_ClickedCallback(app, event)
            % This tool was migrated from GUIDE to App Designer.
            % In GUIDE, clicking on the print tool opened a system
            % print dialog. In App Designer, clicking
            % on the print tool saves a screenshot of the app
            % to a new file.
            
            % Determine the inputs to uiputfile, including default file
            % name, file filter, and dialog title.
            defname = [pwd, filesep, mfilename, '_Screenshot.jpg'];
            filter = {'*.jpg','JPEG Joint Photographic Experts Group Format';...
            '*.png','PNG Portable Network Graphics Format';...
            '*.tiff','TIFF Tag Image File Format';...
            '*.pdf','PDF Portable Document Format'};
            title = 'Save App Screenshot to File';
            
            % Display a modal dialog box that allows users to select or
            % specify a file path for the saved screenshot.
            [file, name, path] = uiputfile(filter, title, defname);
            
            % Check that the file path is valid and then save a screenshot of the app.
            if path ~= 0
            
                exportapp(app.figure1, [name, file]);
            end
        end

        % Value changing function: angle_b
        function angle_bValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Solsys';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uipushtool2 = uipushtool(app.uitoolbar1);
            app.uipushtool2.Tag = 'uipushtool2';
            app.uipushtool2.Tooltip = 'Print Figure';
            app.uipushtool2.ClickedCallback = createCallbackFcn(app, @uipushtool2_ClickedCallback, true);
            app.uipushtool2.Icon = 'uipushtool2_image.png';
            app.uipushtool3 = uipushtool(app.uitoolbar1);
            app.uipushtool3.Tag = 'uipushtool3';
            app.uipushtool3.Tooltip = 'New Figure';
            app.uipushtool3.ClickedCallback = createCallbackFcn(app, @uipushtool3_ClickedCallback, true);
            app.uipushtool3.Icon = 'uipushtool3_image.png';
            app.Tst_plot = uiaxes(app.figure1);
            app.Tst_plot.Tag = 'Tst_plot';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Collector data';
            app.uipanel2.Tag = 'uipanel2';
            app.text11 = uilabel(app.uipanel2);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Value ta,eff';
            app.Input_taeff = uieditfield(app.uipanel2, 'text');
            app.Input_taeff.ValueChangedFcn = createCallbackFcn(app, @Input_taeff_Callback, true);
            app.Input_taeff.Tag = 'Input_taeff';
            app.Input_taeff.Value = '0.8';
            app.text12 = uilabel(app.uipanel2);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Value Fr';
            app.Input_fr = uieditfield(app.uipanel2, 'text');
            app.Input_fr.ValueChangedFcn = createCallbackFcn(app, @Input_fr_Callback, true);
            app.Input_fr.Tag = 'Input_fr';
            app.Input_fr.Value = '0.95';
            app.Input_b = uieditfield(app.uipanel2, 'text');
            app.Input_b.ValueChangedFcn = createCallbackFcn(app, @Input_b_Callback, true);
            app.Input_b.Tag = 'Input_b';
            app.Input_b.Value = '4';
            app.text9 = uilabel(app.uipanel2);
            app.text9.Tag = 'text9';
            app.text9.Text = {'Collector U '; '(W/m^2 K)'};
            app.angle_b = uieditfield(app.uipanel2, 'text');
            app.angle_b.ValueChangedFcn = createCallbackFcn(app, @angle_b_Callback, true);
            app.angle_b.ValueChangingFcn = createCallbackFcn(app, @angle_bValueChanging, true);
            app.angle_b.Tag = 'angle_b';
            app.angle_b.Value = '30';
            app.text54 = uilabel(app.uipanel2);
            app.text54.Tag = 'text54';
            app.text54.Text = 'Angle β';
            app.angle_g = uieditfield(app.uipanel2, 'text');
            app.angle_g.ValueChangedFcn = createCallbackFcn(app, @angle_g_Callback, true);
            app.angle_g.Tag = 'angle_g';
            app.angle_g.Value = '0';
            app.text55 = uilabel(app.uipanel2);
            app.text55.Tag = 'text55';
            app.text55.Text = 'Angle γ';
            app.depthText = uieditfield(app.figure1, 'text');
            app.depthText.ValueChangedFcn = createCallbackFcn(app, @depthText_Callback, true);
            app.depthText.Tag = 'depthText';
            app.depthText.Value = '5.0';
            app.depthSlider = uislider(app.figure1);
            app.depthSlider.MajorTicks = [];
            app.depthSlider.ValueChangedFcn = createCallbackFcn(app, @depthSlider_Callback, true);
            app.depthSlider.MinorTicks = [];
            app.depthSlider.Tag = 'depthSlider';
            app.depthSlider.Value = 5;
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = {'Collector area'; '(m^2)'};
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'SOLar THErmaL SySTEmS EducaTiOnaL Software';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = {'Storage capacity'; '(kJ/K)'};
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Fraction of load';
            app.fText = uilabel(app.figure1);
            app.fText.Tag = 'fText';
            app.fText.Text = 'Static Text';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Load and storage data';
            app.uipanel4.Tag = 'uipanel4';
            app.Input_ust = uieditfield(app.uipanel4, 'text');
            app.Input_ust.ValueChangedFcn = createCallbackFcn(app, @Input_ust_Callback, true);
            app.Input_ust.Tag = 'Input_ust';
            app.Input_ust.Value = '0.35';
            app.text23 = uilabel(app.uipanel4);
            app.text23.Tag = 'text23';
            app.text23.Text = {'Storage heat transfer '; 'coefficient (W/m^2 K)'; ''};
            app.Input_ast = uieditfield(app.uipanel4, 'text');
            app.Input_ast.ValueChangedFcn = createCallbackFcn(app, @Input_ast_Callback, true);
            app.Input_ast.Tag = 'Input_ast';
            app.Input_ast.Value = '0';
            app.text22 = uilabel(app.uipanel4);
            app.text22.Tag = 'text22';
            app.text22.Text = {'Storage area (m^2)'; '                   '};
            app.Input_tlmin = uieditfield(app.uipanel4, 'text');
            app.Input_tlmin.ValueChangedFcn = createCallbackFcn(app, @Input_tlmin_Callback, true);
            app.Input_tlmin.Tag = 'Input_tlmin';
            app.Input_tlmin.Value = '300';
            app.text21 = uilabel(app.uipanel4);
            app.text21.Tag = 'text21';
            app.text21.Text = {'Load minimum'; 'temperature (K)'};
            app.Input_ml = uieditfield(app.uipanel4, 'text');
            app.Input_ml.ValueChangedFcn = createCallbackFcn(app, @Input_ml_Callback, true);
            app.Input_ml.Tag = 'Input_ml';
            app.Input_ml.Value = '0.166';
            app.text20 = uilabel(app.uipanel4);
            app.text20.Tag = 'text20';
            app.text20.Text = {'Load mass'; 'flow rate (kg/s)'};
            app.text19 = uilabel(app.uipanel4);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Load Ql (W)';
            app.Input_ql = uieditfield(app.uipanel4, 'text');
            app.Input_ql.ValueChangedFcn = createCallbackFcn(app, @Input_ql_Callback, true);
            app.Input_ql.Tag = 'Input_ql';
            app.Input_ql.Value = '4200';
            app.text44 = uilabel(app.uipanel4);
            app.text44.Tag = 'text44';
            app.text44.Text = {'Uresidence (W/m^2 K)'; ''};
            app.text46 = uilabel(app.uipanel4);
            app.text46.Tag = 'text46';
            app.text46.Text = 'Aresidence (m^2)';
            app.Uresidence = uieditfield(app.uipanel4, 'text');
            app.Uresidence.ValueChangedFcn = createCallbackFcn(app, @Uresidence_Callback, true);
            app.Uresidence.Tag = 'Uresidence';
            app.Uresidence.Value = '0.6';
            app.Aresidence = uieditfield(app.uipanel4, 'text');
            app.Aresidence.ValueChangedFcn = createCallbackFcn(app, @Aresidence_Callback, true);
            app.Aresidence.Tag = 'Aresidence';
            app.Aresidence.Value = '200';
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = 'Simulation Parameters';
            app.uipanel6.Tag = 'uipanel6';
            app.Month_input = uieditfield(app.uipanel6, 'text');
            app.Month_input.ValueChangedFcn = createCallbackFcn(app, @Month_input_Callback, true);
            app.Month_input.Tag = 'Month_input';
            app.Month_input.Value = '2';
            app.City = uidropdown(app.uipanel6);
            app.City.Items = {'Αθήνα(Ελληνικό)', 'Αθήνα(Φιλαδέλφια)', 'Αγρίνιο', 'Αγχίαλος', 'Αλεξανδρούπολη', 'Αλίαρτος', 'Ανδραβίδα', 'Άραξος', 'Άργος', 'Αργοστόλι', 'Άρτα', 'Δράμα', 'Έδεσσα', 'Ζάκυνθος', 'Ηράκλειο', 'Θεσσαλονίκη', 'Ιεράπετρα', 'Ιωάννινα', 'Καλαμάτα', 'Καρδίτσα', 'Καρπενήσι', 'Κάρυστος', 'Καστοριά', 'Κέρκυρα', 'Κοζάνη', 'Κομοτηνή', 'Κόνιτσα', 'Κόρινθος', 'Κύθηρα', 'Κως', 'Λαμία', 'Λάρισα', 'Λευκάδα', 'Λήμνος', 'Μεθώνη', 'Μήλος', 'Μυτιλήνη', 'Νάξος', 'Ξάνθη', 'Πάρος', 'Πάτρα', 'Πολύγυρος', 'Πύργος', 'Ρέθυμνο', 'Ρόδος', 'Σάμος', 'Σέρρες', 'Σητεία', 'Σκύρος', 'Σούδα', 'Σπάρτη', 'Σύρος', 'Τανάγρα', 'Τρίκαλα(Ημαθίας)', 'Τρίκαλα(Θεσσαλίας)', 'Τρίπολη', 'Τυμπάκιο', 'Φλώρινα', 'Χαλκίδα', 'Χανιά', 'Χίος', 'Καβάλα'};
            app.City.ValueChangedFcn = createCallbackFcn(app, @City_Callback, true);
            app.City.Tag = 'City';
            app.City.Value = 'Αθήνα(Ελληνικό)';
            app.text32 = uilabel(app.uipanel6);
            app.text32.Tag = 'text32';
            app.text32.Text = 'Location';
            app.text33 = uilabel(app.uipanel6);
            app.text33.Tag = 'text33';
            app.text33.Text = 'tend';
            app.tstart = uieditfield(app.uipanel6, 'text');
            app.tstart.ValueChangedFcn = createCallbackFcn(app, @tstart_Callback, true);
            app.tstart.Tag = 'tstart';
            app.tstart.Value = '1';
            app.DHW = uicheckbox(app.uipanel6);
            app.DHW.Tag = 'DHW';
            app.DHW.Text = 'Domestic hot water';
            app.Real_env = uicheckbox(app.uipanel6);
            app.Real_env.Tag = 'Real_env';
            app.Real_env.Text = 'Heating';
            app.text36 = uilabel(app.uipanel6);
            app.text36.Tag = 'text36';
            app.text36.Text = 'tstart';
            app.Start = uibutton(app.uipanel6, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @Start_Callback, true);
            app.Start.Tag = 'Start';
            app.Start.Text = 'START SIMULATION';
            app.Plotting = uidropdown(app.figure1);
            app.Plotting.Items = {'Tstorage [K]', 'Quseful [kW]', 'Efficiency [%] ', 'Qsolar [kW]'};
            app.Plotting.ValueChangedFcn = createCallbackFcn(app, @Plotting_Callback, true);
            app.Plotting.Tag = 'Plotting';
            app.Plotting.Value = 'Tstorage [K]';
            app.mstslider = uislider(app.figure1);
            app.mstslider.Limits = [1 100000];
            app.mstslider.MajorTicks = [];
            app.mstslider.ValueChangedFcn = createCallbackFcn(app, @mstslider_Callback, true);
            app.mstslider.MinorTicks = [];
            app.mstslider.Tag = 'mstslider';
            app.mstslider.Value = 1294;
            app.textmst = uieditfield(app.figure1, 'text');
            app.textmst.ValueChangedFcn = createCallbackFcn(app, @textmst_Callback, true);
            app.textmst.Tag = 'textmst';
            app.textmst.Value = '1294';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Solsys_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Solsys_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end