classdef Solthes_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Hyperlink2                      matlab.ui.control.Hyperlink
        LoadinputConstantSpaceHeatingDomesticHowWaterDHWPanel  matlab.ui.container.Panel
        NumberforresidentsforDHWEditField  matlab.ui.control.NumericEditField
        NumberforresidentsforDHWEditFieldLabel  matlab.ui.control.Label
        WatermainsupplytemperatureKEditField  matlab.ui.control.NumericEditField
        WatermainsupplytemperatureKEditFieldLabel  matlab.ui.control.Label
        RequiredtemperatureforDHWKEditField  matlab.ui.control.NumericEditField
        RequiredtemperatureforDHWKEditFieldLabel  matlab.ui.control.Label
        ConstantloadWEditField          matlab.ui.control.NumericEditField
        ConstantloadWEditFieldLabel     matlab.ui.control.Label
        HouseheattransfercoefficientUWm2KEditField  matlab.ui.control.NumericEditField
        HouseheattransfercoefficientUWm2KEditFieldLabel  matlab.ui.control.Label
        Heatedareaofhousem2EditField    matlab.ui.control.NumericEditField
        Heatedareaofhousem2EditFieldLabel  matlab.ui.control.Label
        BalanceTemperatureKEditField    matlab.ui.control.NumericEditField
        BalanceTemperatureKEditFieldLabel  matlab.ui.control.Label
        MassflowratekgsEditField        matlab.ui.control.NumericEditField
        MassflowratekgsLabel            matlab.ui.control.Label
        minimumtemperatureEditField     matlab.ui.control.NumericEditField
        inimumtemperatureLabel          matlab.ui.control.Label
        ResultsPanel                    matlab.ui.container.Panel
        ExporttoExcelButton             matlab.ui.control.Button
        Averagecollectorefficiency01EditField  matlab.ui.control.NumericEditField
        AveragecollectorefficiencyLabel  matlab.ui.control.Label
        FractionofLoad01EditField       matlab.ui.control.NumericEditField
        FractionofLoad01EditFieldLabel  matlab.ui.control.Label
        SolarcollectorinputPanel        matlab.ui.container.Panel
        SurfaceazimuthangleEditField    matlab.ui.control.NumericEditField
        SurfaceazimuthangleEditFieldLabel  matlab.ui.control.Label
        SlopeEditField                  matlab.ui.control.NumericEditField
        SlopeEditFieldLabel             matlab.ui.control.Label
        Densityoffluidkgm3EditField     matlab.ui.control.NumericEditField
        Densityofthefluidkgm3Label      matlab.ui.control.Label
        HeatremovalparameterFREditField  matlab.ui.control.NumericEditField
        HeatremovalparameterFREditFieldLabel  matlab.ui.control.Label
        ta_effEditField                 matlab.ui.control.NumericEditField
        ta_effEditFieldLabel            matlab.ui.control.Label
        SurfaceAm2EditField             matlab.ui.control.NumericEditField
        SurfaceAm2EditFieldLabel        matlab.ui.control.Label
        SpecificheatofworkingfluidJkgKEditField  matlab.ui.control.NumericEditField
        SpecificheatofworkingfluidJkgKEditFieldLabel  matlab.ui.control.Label
        HeattransfercoefficientcolWm2KEditField  matlab.ui.control.NumericEditField
        HeattransfercoefficientcolWm2KEditFieldLabel  matlab.ui.control.Label
        StorageinputPanel               matlab.ui.container.Panel
        HeattransfercoefficientstWm2KEditField  matlab.ui.control.NumericEditField
        HeattransfercoefficientstWm2KEditFieldLabel  matlab.ui.control.Label
        MaximumstoragetemperatureKEditField  matlab.ui.control.NumericEditField
        MaximumstoragetemperatureKEditFieldLabel  matlab.ui.control.Label
        InitialstoragetemperatureKEditField  matlab.ui.control.NumericEditField
        InitialstoragetemperatureKLabel  matlab.ui.control.Label
        LengthdiameterratiooftankmEditField  matlab.ui.control.NumericEditField
        LengthdiameterratiooftankmEditFieldLabel  matlab.ui.control.Label
        Volumestoragem3EditField        matlab.ui.control.NumericEditField
        Volumestoragem3EditFieldLabel   matlab.ui.control.Label
        WeatherdatafilesPanel           matlab.ui.container.Panel
        DownloaddatafromPVGISButton     matlab.ui.control.Button
        FormatEditField                 matlab.ui.control.EditField
        FormatEditFieldLabel            matlab.ui.control.Label
        EndyearEditField                matlab.ui.control.NumericEditField
        EndyearEditFieldLabel           matlab.ui.control.Label
        StartyearEditField              matlab.ui.control.NumericEditField
        StartyearEditFieldLabel         matlab.ui.control.Label
        SiteEditField                   matlab.ui.control.EditField
        SiteEditFieldLabel              matlab.ui.control.Label
        LongitudeEditField              matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        LatitudeEditField               matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        LoadweatherdatafilefromcomputerButton  matlab.ui.control.Button
        MaininputsPanel                 matlab.ui.container.Panel
        TimedependentloadCheckBox       matlab.ui.control.CheckBox
        SpaceheatingCheckBox            matlab.ui.control.CheckBox
        DomestichotwaterCheckBox        matlab.ui.control.CheckBox
        StoptimehrEditField             matlab.ui.control.NumericEditField
        StoptimehrLabel                 matlab.ui.control.Label
        StarttimehrEditField            matlab.ui.control.NumericEditField
        StarttimehrLabel                matlab.ui.control.Label
        SOLTHESSOLarTHErmaLSySTEmSEducaTiOnaLSoftwareLabel  matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        Plotting                        matlab.ui.control.DropDown
        StartSimulationButton           matlab.ui.control.Button
        plot                            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        filename % Full path of the weather data
        output_data % The results from the simulation.
        time, TST, TA, QL, DC, DL, QU, N_A %Output data for plotting
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: HeattransfercoefficientcolWm2KEditField, 
        % ...and 2 other components
        function Start_Simulation_Button(app, event)
            %Type of solar thermal system, idhw=1 for domestic how water, for anything else idhw=0
            idhw=app.TimedependentloadCheckBox.Value;
            %Type of load, for constant load sload=0, for time dependent load sload=1
            sload=app.TimedependentloadCheckBox.Value;
            %isp=1 if it is a space heating system in order to calculate the degree hours
            isp=app.SpaceheatingCheckBox.Value;
            %Time of start in hours%
            tstart = app.StarttimehrEditField.Value;
            %Time of end in hours%
            tend =app.StoptimehrEditField.Value;
            %COLLECTOR DATA INPUT%
            %Collector slope in degrees
            bi=app.SlopeEditField.Value;
            %Surface azimuth angle
            gam=app.SurfaceazimuthangleEditField.Value;
            %Insert effective ground reflectance, pr=0.2 for ground NOT covered by now,%pr=0.7 for snow covered ground
            pr=0.2;
            %Check if a file has been specified.
            while isempty(app.filename) 
                uiwait(msgbox(["Please select a file from computer or download a csv file from PVGIS."; ...
                    "Then press start simulation."],"Weather data file missing","error")) 
            end
            %Load solar radation on sloped surface and temperature from Solar_data function
            [qs_year,ta_year]=solar_data(app.filename,bi,gam,pr);
                
            %Collector surface A [m^2]
            A=app.SurfaceAm2EditField.Value;
            %Collector property ta_eff
            ta_eff =app.ta_effEditField.Value ;
            %Collector heat removal parameter FR
            Fr =app.HeatremovalparameterFREditField.Value;
            %Collector heat transfer coefficient U [W/mA2*K] or collector value b can be inserted and then U is computed
            U=app.HeattransfercoefficientcolWm2KEditField.Value;
            %Specific heat of working fluid [J/kg*K]%
            c = app.SpecificheatofworkingfluidJkgKEditField.Value;   
            %density of the fluid [kg/m3]
            rho=app.Densityoffluidkgm3EditField.Value;  
            %STORAGE DATA INPUT%
            Vst=app.Volumestoragem3EditField.Value;
            rlndia=app.LengthdiameterratiooftankmEditField.Value;
            %Storage heat transfer coefficient Ust [W/m^2*K]
            Ust =app.HeattransfercoefficientstWm2KEditField.Value;
            %Initial storage temperature Tst,initial [K]
            Tst =app.InitialstoragetemperatureKEditField.Value;
            %Insert maximum storage temperature Tstmax allowed [K]
            Tstmax =app.MaximumstoragetemperatureKEditField.Value;
            %LOAD DATA INPUT%
            % load Ql [W] if the system is not a space heating or dhw system
            if isp~=1 && idhw~=1
                Ql=app.ConstantloadWEditField.Value;	
            end
            %Insert load minimum temperature Tl,min [K]
            Tlmin =app.minimumtemperatureEditField.Value;
            %Insert load mass flow rate ml [Kg/s]
            ml=app.MassflowratekgsEditField.Value;
            %Space heating
            %Insert balance temperature in [K]
            Tb=app.BalanceTemperatureKEditField.Value;
            %Insert total area of the house
            Ahouse=app.Heatedareaofhousem2EditField.Value;
            %Insert house heat transfer coefficient U [W/mA2*K] 
            Uhouse=app.HouseheattransfercoefficientUWm2KEditField.Value;
            %Domestic hot water
            %Load input, sload=1 for time dependent load otherwise sload=0 for contant load
            if idhw==1
                %Insert required temperature Treq [K]
                Treq=app.RequiredtemperatureforDHWKEditField.Value;
                %Insert water main supply temperature Tsup [K]
                Tsup=app.WatermainsupplytemperatureKEditField.Value;
                %Insert number of residents
                residents=app.NumberforresidentsforDHWEditField.Value;
                ml_max=residents*100; %Liters per day per person * persons=Liters per day=Kg per day 
                if (sload==1)   
                    %Insert load time in minutes, 24 points, usually the domestic hot
                    %water is a 24-hour profile
                    %Insert load time in hours, 24 points
                    hr = linspace(1,24,24);
                    %Insert load in kg/h, 24 points if it is DHW system
                    ml_u=[0,0,0,0,0,0,0.2*ml_max,0.2*ml_max,0,0,0,0.1*ml_max,0.1*ml_max,0,0,0,0,0.2*ml_max,0.2*ml_max,0,0,0,0,0];
                    %Hours of the RAND profile
                    hr_load=24;  
                end
            end
            %Insert time between output in minutes%
            tr =60;
            %Create vectors   
            Vec=[];
            TST(1,tend-tstart)=Tst;QU=zeros(1,tend-tstart);N_A=zeros(1,tend-tstart);
            DL=zeros(1,tend-tstart);DC=zeros(1,tend-tstart);TA(1,tend-tstart)=mean(ta_year(tstart:tend));
            QL=zeros(1,tend-tstart);
            app.time=linspace(tstart,tend,tend+1-tstart);
            ttot=linspace(1,8760,8760); %All the hours of the year
            %% *******************Calculation********************%
            [app.output_data, app.TST, app.TA, app.QL, app.DC, app.DL, app.QU, app.N_A, app.FractionofLoad01EditField.Value,app.Averagecollectorefficiency01EditField.Value] = ...
                calculation(A, Vst, rho, c, rlndia, tstart, ta_year, tend, qs_year, U, Tst, Vec, TST, TA, QL, tr, ttot, isp, Tb, Uhouse, Ahouse, idhw, ml_u, Treq, Tsup, ta_eff, Tstmax, Fr, ml, Tlmin, Ust, DC, DL, QU, N_A);
            %Initial plot
            plot(app.plot,app.time,app.TST);
            xlabel(app.plot,'t (hr)');
            ylabel(app.plot,'Tst(K)');
            grid(app.plot,'on');
        end

        % Button pushed function: LoadweatherdatafilefromcomputerButton
        function Load_weather_data_file_computer_Button(app, event)
            [file,path] = uigetfile('*.csv');  %open a mat file
            %Get the full path
            app.filename=fullfile(path,file);
        end

        % Button pushed function: ExporttoExcelButton
        function Export_Excel_Button(app, event)
            if isempty(app.output_data)==1
                msgbox('Simulation not started yet!',"Error","error")
            else
                msgbox('Writing file to xls format may take some time depending on the simulation hours.')
                col_header={'Hour','Tfin','Tfout','Tst','Tlin','Tlout','Ta','dc','dl','Qs','Qu','Ql','Qdhw','Qst','n'};     %Row cell array (for column labels)
                xlswrite ('SOLTHES_output',app.output_data,'Results','B2');
                xlswrite('SOLTHES_output',col_header,'Results','B1');     %Write column header
            end
        end

        % Button pushed function: DownloaddatafromPVGISButton
        function Download_data_PVGIS_Button(app, event)
                input_data=strcat(string(app.SiteEditField.Value),',',string(app.LatitudeEditField.Value),',',string(app.LongitudeEditField.Value),',',string(app.StartyearEditField.Value),',',string(app.EndyearEditField.Value),',',string(app.FormatEditField.Value));
                %Call python from Matlab but check first that it is set-up correctly.
                if isempty(pyenv)
                    uiwait(msgbox('Python is not set up in MATLAB. Using webread from Matlab. Press ok to continue.',"Warning"));
                    %create URL
                    url=strcat("https://re.jrc.ec.europa.eu/api/tmy?lat=",string(app.LatitudeEditField.Value),"&lon=",string(app.LongitudeEditField.Value),"&startyear=",string(app.StartyearEditField.Value),"&endyear=",string(app.EndyearEditField.Value),"&outputformat=",string(app.FormatEditField.Value));
                    %WEBREAD and then directly write to a file.
                    writematrix(webread(url),strcat('../data/data_',string(app.SiteEditField.Value),'.',string(app.FormatEditField.Value)))
                else
                    %Call Python subroutine to download the data and store them to 
                    %folder data
                    status_code=py.data_PVGIS.site_data(input_data);
                    %check the status code to see if the data are downloaded properly.
                    while (int64(status_code)==400)
                         prompt = 'Please enter data in correct form of, e.g., Larissa,39.64,22.41,2005,2016,csv  ';
                         input_data = input(prompt,'s');
                         status_code=py.data_PVGIS.site_data(input_data);
                    end
                end
                %Full path
                app.filename=char(fullfile(pwd, strcat('../data/data_',string(app.SiteEditField.Value),'.',string(app.FormatEditField.Value))));
                %Relative path
                %app.filename=char((strcat('../data/data_',string(app.SiteEditField.Value),'.',string(app.FormatEditField.Value))));
                %Test if it is downloaded.
                if isempty(app.filename)==false
                    msgbox(strcat("Data downloaded at",app.filename),"Success")
                end

        end

        % Value changed function: Plotting
        function PlottingValueChanged(app, event)
            %Plot basic quantities
            switch app.Plotting.Value
                case 'Quseful [kW]'
                    plot(app.plot,app.time,app.QU);
                    xlabel(app.plot,'t (hr)');
                    ylabel(app.plot,'Qu(kW)');
                    grid(app.plot,'on');
                case 'Efficiency [%] '
                    plot(app.plot,app.time,app.N_A);
                    xlabel(app.plot,'t (hr)');
                    ylabel(app.plot,'n(%)');
                    grid(app.plot,'on');
                case 'Qload [kW]'
                    plot(app.plot,app.time,app.QL);
                    xlabel(app.plot,'t (hr)');
                    ylabel(app.plot,'Qload(kW)');
                    grid(app.plot,'on'); 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.plot = uiaxes(app.UIFigure);
            xlabel(app.plot, 'time (hr)')
            app.plot.ButtonDownFcn = createCallbackFcn(app, @Start_Simulation_Button, true);
            app.plot.Tag = 'Tst_plot';
            app.StartSimulationButton = uibutton(app.UIFigure, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @Start_Simulation_Button, true);
            app.StartSimulationButton.Tag = 'start';
            app.StartSimulationButton.Text = 'Start Simulation';
            app.Plotting = uidropdown(app.UIFigure);
            app.Plotting.Items = {'Tstorage [K]', 'Quseful [kW]', 'Efficiency [%] ', 'Qload [kW]'};
            app.Plotting.ValueChangedFcn = createCallbackFcn(app, @PlottingValueChanged, true);
            app.Plotting.Tag = 'Plotting';
            app.Plotting.Value = 'Tstorage [K]';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://github.com/aelalex/solthes';
            app.Hyperlink.Text = 'Solthes Github';
            app.SOLTHESSOLarTHErmaLSySTEmSEducaTiOnaLSoftwareLabel = uilabel(app.UIFigure);
            app.SOLTHESSOLarTHErmaLSySTEmSEducaTiOnaLSoftwareLabel.Interpreter = 'latex';
            app.SOLTHESSOLarTHErmaLSySTEmSEducaTiOnaLSoftwareLabel.Text = 'SOLTHES - SOLar THErmaL SySTEmS EducaTiOnaL Software';
            app.MaininputsPanel = uipanel(app.UIFigure);
            app.MaininputsPanel.Title = 'Main inputs';
            app.StarttimehrLabel = uilabel(app.MaininputsPanel);
            app.StarttimehrLabel.Text = 'Start time [hr]';
            app.StarttimehrEditField = uieditfield(app.MaininputsPanel, 'numeric');
            app.StarttimehrEditField.Value = 1;
            app.StoptimehrLabel = uilabel(app.MaininputsPanel);
            app.StoptimehrLabel.Text = 'Stop time [hr]';
            app.StoptimehrEditField = uieditfield(app.MaininputsPanel, 'numeric');
            app.StoptimehrEditField.Value = 24;
            app.DomestichotwaterCheckBox = uicheckbox(app.MaininputsPanel);
            app.DomestichotwaterCheckBox.Text = 'Domestic hot water';
            app.DomestichotwaterCheckBox.Value = true;
            app.SpaceheatingCheckBox = uicheckbox(app.MaininputsPanel);
            app.SpaceheatingCheckBox.Text = 'Space heating';
            app.SpaceheatingCheckBox.Value = true;
            app.TimedependentloadCheckBox = uicheckbox(app.MaininputsPanel);
            app.TimedependentloadCheckBox.Text = 'Time dependent load';
            app.TimedependentloadCheckBox.Value = true;
            app.WeatherdatafilesPanel = uipanel(app.UIFigure);
            app.WeatherdatafilesPanel.Title = 'Weather data files';
            app.LoadweatherdatafilefromcomputerButton = uibutton(app.WeatherdatafilesPanel, 'push');
            app.LoadweatherdatafilefromcomputerButton.ButtonPushedFcn = createCallbackFcn(app, @Load_weather_data_file_computer_Button, true);
            app.LoadweatherdatafilefromcomputerButton.Text = 'Load weather data file from computer';
            app.LatitudeEditFieldLabel = uilabel(app.WeatherdatafilesPanel);
            app.LatitudeEditFieldLabel.Text = 'Latitude  ';
            app.LatitudeEditField = uieditfield(app.WeatherdatafilesPanel, 'numeric');
            app.LatitudeEditField.Value = 39.64;
            app.LongitudeEditFieldLabel = uilabel(app.WeatherdatafilesPanel);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.LongitudeEditField = uieditfield(app.WeatherdatafilesPanel, 'numeric');
            app.LongitudeEditField.Value = 22.41;
            app.SiteEditFieldLabel = uilabel(app.WeatherdatafilesPanel);
            app.SiteEditFieldLabel.Text = 'Site';
            app.SiteEditField = uieditfield(app.WeatherdatafilesPanel, 'text');
            app.SiteEditField.Value = 'Larissa';
            app.StartyearEditFieldLabel = uilabel(app.WeatherdatafilesPanel);
            app.StartyearEditFieldLabel.Text = 'Start year';
            app.StartyearEditField = uieditfield(app.WeatherdatafilesPanel, 'numeric');
            app.StartyearEditField.Value = 2005;
            app.EndyearEditFieldLabel = uilabel(app.WeatherdatafilesPanel);
            app.EndyearEditFieldLabel.Text = 'End year';
            app.EndyearEditField = uieditfield(app.WeatherdatafilesPanel, 'numeric');
            app.EndyearEditField.Value = 2016;
            app.FormatEditFieldLabel = uilabel(app.WeatherdatafilesPanel);
            app.FormatEditFieldLabel.Text = 'Format';
            app.FormatEditField = uieditfield(app.WeatherdatafilesPanel, 'text');
            app.FormatEditField.Value = 'csv';
            app.DownloaddatafromPVGISButton = uibutton(app.WeatherdatafilesPanel, 'push');
            app.DownloaddatafromPVGISButton.ButtonPushedFcn = createCallbackFcn(app, @Download_data_PVGIS_Button, true);
            app.DownloaddatafromPVGISButton.Text = 'Download data from PVGIS';
            app.StorageinputPanel = uipanel(app.UIFigure);
            app.StorageinputPanel.Title = 'Storage input';
            app.Volumestoragem3EditFieldLabel = uilabel(app.StorageinputPanel);
            app.Volumestoragem3EditFieldLabel.Text = 'Volume storage [m3]';
            app.Volumestoragem3EditField = uieditfield(app.StorageinputPanel, 'numeric');
            app.Volumestoragem3EditField.Limits = [0 Inf];
            app.Volumestoragem3EditField.Value = 0.5;
            app.LengthdiameterratiooftankmEditFieldLabel = uilabel(app.StorageinputPanel);
            app.LengthdiameterratiooftankmEditFieldLabel.Text = 'Length-diameter ratio of tank [m]';
            app.LengthdiameterratiooftankmEditField = uieditfield(app.StorageinputPanel, 'numeric');
            app.LengthdiameterratiooftankmEditField.Limits = [0 Inf];
            app.LengthdiameterratiooftankmEditField.Value = 3;
            app.InitialstoragetemperatureKLabel = uilabel(app.StorageinputPanel);
            app.InitialstoragetemperatureKLabel.Text = 'Initial storage temperature [K]';
            app.InitialstoragetemperatureKEditField = uieditfield(app.StorageinputPanel, 'numeric');
            app.InitialstoragetemperatureKEditField.Limits = [0 Inf];
            app.InitialstoragetemperatureKEditField.Value = 300;
            app.MaximumstoragetemperatureKEditFieldLabel = uilabel(app.StorageinputPanel);
            app.MaximumstoragetemperatureKEditFieldLabel.Text = 'Maximum storage temperature [K]';
            app.MaximumstoragetemperatureKEditField = uieditfield(app.StorageinputPanel, 'numeric');
            app.MaximumstoragetemperatureKEditField.Limits = [0 Inf];
            app.MaximumstoragetemperatureKEditField.Value = 373;
            app.HeattransfercoefficientstWm2KEditFieldLabel = uilabel(app.StorageinputPanel);
            app.HeattransfercoefficientstWm2KEditFieldLabel.Text = 'Heat transfer coefficient st [W/m^2*K]';
            app.HeattransfercoefficientstWm2KEditField = uieditfield(app.StorageinputPanel, 'numeric');
            app.HeattransfercoefficientstWm2KEditField.Limits = [0 Inf];
            app.HeattransfercoefficientstWm2KEditField.Value = 0.35;
            app.SolarcollectorinputPanel = uipanel(app.UIFigure);
            app.SolarcollectorinputPanel.Title = 'Solar collector input';
            app.HeattransfercoefficientcolWm2KEditFieldLabel = uilabel(app.SolarcollectorinputPanel);
            app.HeattransfercoefficientcolWm2KEditFieldLabel.Text = 'Heat transfer coefficient col [W/m^2*K]';
            app.HeattransfercoefficientcolWm2KEditField = uieditfield(app.SolarcollectorinputPanel, 'numeric');
            app.HeattransfercoefficientcolWm2KEditField.Limits = [0 Inf];
            app.HeattransfercoefficientcolWm2KEditField.ValueChangedFcn = createCallbackFcn(app, @Start_Simulation_Button, true);
            app.HeattransfercoefficientcolWm2KEditField.Value = 5.33;
            app.SpecificheatofworkingfluidJkgKEditFieldLabel = uilabel(app.SolarcollectorinputPanel);
            app.SpecificheatofworkingfluidJkgKEditFieldLabel.Text = 'Specific heat of working fluid [J/kg*K]';
            app.SpecificheatofworkingfluidJkgKEditField = uieditfield(app.SolarcollectorinputPanel, 'numeric');
            app.SpecificheatofworkingfluidJkgKEditField.Limits = [0 Inf];
            app.SpecificheatofworkingfluidJkgKEditField.Value = 4200;
            app.SurfaceAm2EditFieldLabel = uilabel(app.SolarcollectorinputPanel);
            app.SurfaceAm2EditFieldLabel.Text = 'Surface A [m^2]';
            app.SurfaceAm2EditField = uieditfield(app.SolarcollectorinputPanel, 'numeric');
            app.SurfaceAm2EditField.Limits = [0 Inf];
            app.SurfaceAm2EditField.Value = 20;
            app.ta_effEditFieldLabel = uilabel(app.SolarcollectorinputPanel);
            app.ta_effEditFieldLabel.Text = 'ta_eff';
            app.ta_effEditField = uieditfield(app.SolarcollectorinputPanel, 'numeric');
            app.ta_effEditField.Limits = [0 1];
            app.ta_effEditField.Value = 0.822;
            app.HeatremovalparameterFREditFieldLabel = uilabel(app.SolarcollectorinputPanel);
            app.HeatremovalparameterFREditFieldLabel.Text = 'Heat removal parameter FR';
            app.HeatremovalparameterFREditField = uieditfield(app.SolarcollectorinputPanel, 'numeric');
            app.HeatremovalparameterFREditField.Limits = [0 1];
            app.HeatremovalparameterFREditField.Value = 0.9;
            app.Densityofthefluidkgm3Label = uilabel(app.SolarcollectorinputPanel);
            app.Densityofthefluidkgm3Label.Text = 'Density of fluid [kg/m^3]';
            app.Densityoffluidkgm3EditField = uieditfield(app.SolarcollectorinputPanel, 'numeric');
            app.Densityoffluidkgm3EditField.Value = 1000;
            app.SlopeEditFieldLabel = uilabel(app.SolarcollectorinputPanel);
            app.SlopeEditFieldLabel.Text = 'Slope';
            app.SlopeEditField = uieditfield(app.SolarcollectorinputPanel, 'numeric');
            app.SlopeEditField.Value = 40;
            app.SurfaceazimuthangleEditFieldLabel = uilabel(app.SolarcollectorinputPanel);
            app.SurfaceazimuthangleEditFieldLabel.Text = 'Surface azimuth angle';
            app.SurfaceazimuthangleEditField = uieditfield(app.SolarcollectorinputPanel, 'numeric');
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.FractionofLoad01EditFieldLabel = uilabel(app.ResultsPanel);
            app.FractionofLoad01EditFieldLabel.Text = 'Fraction of Load (0-1)';
            app.FractionofLoad01EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.FractionofLoad01EditField.ValueDisplayFormat = '%.2f';
            app.AveragecollectorefficiencyLabel = uilabel(app.ResultsPanel);
            app.AveragecollectorefficiencyLabel.Text = 'Average collector efficiency (0-1)';
            app.Averagecollectorefficiency01EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.Averagecollectorefficiency01EditField.ValueDisplayFormat = '%.2f';
            app.ExporttoExcelButton = uibutton(app.ResultsPanel, 'push');
            app.ExporttoExcelButton.ButtonPushedFcn = createCallbackFcn(app, @Export_Excel_Button, true);
            app.ExporttoExcelButton.Text = 'Export to Excel';
            app.LoadinputConstantSpaceHeatingDomesticHowWaterDHWPanel = uipanel(app.UIFigure);
            app.LoadinputConstantSpaceHeatingDomesticHowWaterDHWPanel.Title = 'Load input (Constant, Space Heating, Domestic How Water (DHW))';
            app.inimumtemperatureLabel = uilabel(app.LoadinputConstantSpaceHeatingDomesticHowWaterDHWPanel);
            app.inimumtemperatureLabel.Text = 'Μinimum temperature [Κ]';
            app.minimumtemperatureEditField = uieditfield(app.LoadinputConstantSpaceHeatingDomesticHowWaterDHWPanel, 'numeric');
            app.minimumtemperatureEditField.Limits = [0 Inf];
            app.minimumtemperatureEditField.Value = 300;
            app.MassflowratekgsLabel = uilabel(app.LoadinputConstantSpaceHeatingDomesticHowWaterDHWPanel);
            app.MassflowratekgsLabel.Text = 'Mass flow rate [kg/s]';
            app.MassflowratekgsEditField = uieditfield(app.LoadinputConstantSpaceHeatingDomesticHowWaterDHWPanel, 'numeric');
            app.MassflowratekgsEditField.Limits = [0 Inf];
            app.MassflowratekgsEditField.Value = 0.0166666666666667;
            app.BalanceTemperatureKEditFieldLabel = uilabel(app.LoadinputConstantSpaceHeatingDomesticHowWaterDHWPanel);
            app.BalanceTemperatureKEditFieldLabel.Text = 'Balance Temperature (K)';
            app.BalanceTemperatureKEditField = uieditfield(app.LoadinputConstantSpaceHeatingDomesticHowWaterDHWPanel, 'numeric');
            app.BalanceTemperatureKEditField.Limits = [0 Inf];
            app.BalanceTemperatureKEditField.Value = 291.3;
            app.Heatedareaofhousem2EditFieldLabel = uilabel(app.LoadinputConstantSpaceHeatingDomesticHowWaterDHWPanel);
            app.Heatedareaofhousem2EditFieldLabel.Text = 'Heated area of house [m^2]';
            app.Heatedareaofhousem2EditField = uieditfield(app.LoadinputConstantSpaceHeatingDomesticHowWaterDHWPanel, 'numeric');
            app.Heatedareaofhousem2EditField.Value = 320;
            app.HouseheattransfercoefficientUWm2KEditFieldLabel = uilabel(app.LoadinputConstantSpaceHeatingDomesticHowWaterDHWPanel);
            app.HouseheattransfercoefficientUWm2KEditFieldLabel.Text = 'House heat transfer coefficient U  [W/m^2*K]';
            app.HouseheattransfercoefficientUWm2KEditField = uieditfield(app.LoadinputConstantSpaceHeatingDomesticHowWaterDHWPanel, 'numeric');
            app.HouseheattransfercoefficientUWm2KEditField.Limits = [0 Inf];
            app.HouseheattransfercoefficientUWm2KEditField.Value = 0.55;
            app.ConstantloadWEditFieldLabel = uilabel(app.LoadinputConstantSpaceHeatingDomesticHowWaterDHWPanel);
            app.ConstantloadWEditFieldLabel.Text = 'Constant load [W]';
            app.ConstantloadWEditField = uieditfield(app.LoadinputConstantSpaceHeatingDomesticHowWaterDHWPanel, 'numeric');
            app.ConstantloadWEditField.Limits = [0 Inf];
            app.ConstantloadWEditField.Value = 2000;
            app.RequiredtemperatureforDHWKEditFieldLabel = uilabel(app.LoadinputConstantSpaceHeatingDomesticHowWaterDHWPanel);
            app.RequiredtemperatureforDHWKEditFieldLabel.Text = 'Required temperature for DHW [K]';
            app.RequiredtemperatureforDHWKEditField = uieditfield(app.LoadinputConstantSpaceHeatingDomesticHowWaterDHWPanel, 'numeric');
            app.RequiredtemperatureforDHWKEditField.Value = 318;
            app.WatermainsupplytemperatureKEditFieldLabel = uilabel(app.LoadinputConstantSpaceHeatingDomesticHowWaterDHWPanel);
            app.WatermainsupplytemperatureKEditFieldLabel.Text = 'Water main supply temperature [K]';
            app.WatermainsupplytemperatureKEditField = uieditfield(app.LoadinputConstantSpaceHeatingDomesticHowWaterDHWPanel, 'numeric');
            app.WatermainsupplytemperatureKEditField.Limits = [0 Inf];
            app.WatermainsupplytemperatureKEditField.Value = 283;
            app.NumberforresidentsforDHWEditFieldLabel = uilabel(app.LoadinputConstantSpaceHeatingDomesticHowWaterDHWPanel);
            app.NumberforresidentsforDHWEditFieldLabel.Text = 'Number for residents for DHW ';
            app.NumberforresidentsforDHWEditField = uieditfield(app.LoadinputConstantSpaceHeatingDomesticHowWaterDHWPanel, 'numeric');
            app.NumberforresidentsforDHWEditField.Value = 8;
            app.Hyperlink2 = uihyperlink(app.UIFigure);
            app.Hyperlink2.URL = 'https://re.jrc.ec.europa.eu/pvg_tools/en/';
            app.Hyperlink2.Text = 'PVGIS webtool';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Solthes_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end